classdef GUI_Robot_Scara < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EnviarTrayectoriaButton  matlab.ui.control.Button
        DesconectarButton        matlab.ui.control.Button
        ConectarButton           matlab.ui.control.Button
        time                     matlab.ui.control.NumericEditField
        TiempoEditFieldLabel     matlab.ui.control.Label
        FinalLabel               matlab.ui.control.Label
        Pos_yf                   matlab.ui.control.NumericEditField
        YiLabel_2                matlab.ui.control.Label
        Pos_xf                   matlab.ui.control.NumericEditField
        XiLabel_2                matlab.ui.control.Label
        InicialLabel             matlab.ui.control.Label
        GraficarposicionButton   matlab.ui.control.Button
        Pos_yi                   matlab.ui.control.NumericEditField
        YiLabel                  matlab.ui.control.Label
        GUISCARALabel            matlab.ui.control.Label
        Pos_xi                   matlab.ui.control.NumericEditField
        XiLabel                  matlab.ui.control.Label
        Grafica_angacel          matlab.ui.control.UIAxes
        Grafica_angvel           matlab.ui.control.UIAxes
        Grafica_ang              matlab.ui.control.UIAxes
        Grafica_vel              matlab.ui.control.UIAxes
        Grafica_pos              matlab.ui.control.UIAxes
        Grafica_acel             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        valor_x
        valor_y% Description
        conteo % Description
        the_1
        the_2
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: GraficarposicionButton
        function GraficarposicionButtonPushed(app, event)
            ax_0 = app.Pos_xi.Value;
            ax_1 = 0;
            ax_2 = (3/(app.time.Value^2))*(app.Pos_xf.Value-app.Pos_xi.Value);
            ax_3 = (-2/(app.time.Value^3))*(app.Pos_xf.Value-app.Pos_xi.Value);

            ay_0 = app.Pos_yi.Value;
            ay_1 = 0;
            ay_2 = (3/(app.time.Value^2))*(app.Pos_yf.Value-app.Pos_yi.Value);
            ay_3 = (-2/(app.time.Value^3))*(app.Pos_yf.Value-app.Pos_yi.Value);
            cont = 1;
            l2=142.32;
            l1=115;
            val=1;
            tiempo = 1:0.1:app.time.Value
            for t=1:0.1:app.time.Value
                x(1,cont) = ax_0 + ax_1*t + ax_2*t^2 + ax_3*t^3;
                xp(1,cont) = ax_1 + 2*ax_2*t + 3*ax_3*t^2;
                xpp(1,cont) = 2*ax_2 + 6*ax_3*t;

                y(1,cont) = ay_0 + ay_1*t + ay_2*t^2 + ay_3*t^3;
                yp(1,cont) = ay_1 + 2*ay_2*t + 3*ay_3*t^2;
                ypp(1,cont) = 2*ay_2 + 6*ay_3*t;

                the2(1,val)=real(180-acosd(complex((l2^2+l1^2-x(1,val)^2-y(1,val)^2)/(2*l1*l2))));
                the1(1,val)=real(atan2d(y(1,val),x(1,val))-asind(l2*sind(180-the2(1,val))/sqrt(x(1,val)^2+y(1,val)^2)));

                app.the_1 = the1(1,val);
                app.the_2 = the2(1,val);

                cont=1+cont;
                val=val+1;
            end
            g = app.the_1;
            f = app.the_2;
            tiempo1 = 1:0.1:app.time.Value-0.1;
            tiempo2 = 1:0.1:app.time.Value-0.2;
            the1p = diff(the1);
            the2p = diff(the2);
            the1pp = diff(the1p);
            the2pp = diff(the2p);
            plot(app.Grafica_pos,tiempo,x(1,:),tiempo,y(1,:));
            plot(app.Grafica_vel,tiempo,xp(1,:),tiempo,yp(1,:));
            plot(app.Grafica_acel,tiempo,xpp(1,:),tiempo,ypp(1,:));
            plot(app.Grafica_ang,tiempo,the1(1,:),tiempo,the2(1,:));
            plot(app.Grafica_angvel,tiempo1,the1p(1,:),tiempo1,the2p(1,:));
            plot(app.Grafica_angacel,tiempo2,the1pp(1,:),tiempo2,the2pp(1,:));

            app.the_1 = app.the_1*(-1);
            app.the_2 = -app.the_1-app.the_2;
            A = convertCharsToStrings(num2str(round((app.the_1),0)))
            B = convertCharsToStrings(num2str(round((app.the_2),0)))
        end

        % Button pushed function: EnviarTrayectoriaButton
        function EnviarTrayectoriaButtonPushed(app, event)
            global s1 s2
            A = convertCharsToStrings(num2str(round((app.the_1),0)))
            B = convertCharsToStrings(num2str(round((app.the_2),0)))
            writeline(s1,A)
            writeline(s2,B)
        end

        % Button pushed function: DesconectarButton
        function DesconectarButtonPushed(app, event)
            global s1 s2
            fclose(s1);
            fclose(s2);
            clear all
        end

        % Button pushed function: ConectarButton
        function ConectarButtonPushed(app, event)
            global s1 s2
            freeports = serialportlist("available")
            s1 = serialport("COM4",115200);
            s2 = serialport("COM10",115200);
            configureTerminator(s1,"CR")
            configureTerminator(s2,"CR")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Grafica_acel = uiaxes(app.UIFigure);
            title(app.Grafica_acel, 'Xpp , Ypp')
            xlabel(app.Grafica_acel, 'X')
            ylabel(app.Grafica_acel, 'Y')
            zlabel(app.Grafica_acel, 'Z')
            app.Grafica_pos = uiaxes(app.UIFigure);
            title(app.Grafica_pos, 'X , Y')
            xlabel(app.Grafica_pos, 'X')
            ylabel(app.Grafica_pos, 'Y')
            zlabel(app.Grafica_pos, 'Z')
            app.Grafica_vel = uiaxes(app.UIFigure);
            title(app.Grafica_vel, 'Xp , Yp')
            xlabel(app.Grafica_vel, 'X')
            ylabel(app.Grafica_vel, 'Y')
            zlabel(app.Grafica_vel, 'Z')
            app.Grafica_ang = uiaxes(app.UIFigure);
            title(app.Grafica_ang, 'The1 , The2')
            xlabel(app.Grafica_ang, 'X')
            ylabel(app.Grafica_ang, 'Y')
            zlabel(app.Grafica_ang, 'Z')
            app.Grafica_angvel = uiaxes(app.UIFigure);
            title(app.Grafica_angvel, 'The1p , The2p')
            xlabel(app.Grafica_angvel, 'X')
            ylabel(app.Grafica_angvel, 'Y')
            zlabel(app.Grafica_angvel, 'Z')
            app.Grafica_angacel = uiaxes(app.UIFigure);
            title(app.Grafica_angacel, 'The1pp , The2pp')
            xlabel(app.Grafica_angacel, 'X')
            ylabel(app.Grafica_angacel, 'Y')
            zlabel(app.Grafica_angacel, 'Z')
            app.XiLabel = uilabel(app.UIFigure);
            app.XiLabel.Text = 'Xi';
            app.Pos_xi = uieditfield(app.UIFigure, 'numeric');
            app.Pos_xi.Limits = [-180 180];
            app.GUISCARALabel = uilabel(app.UIFigure);
            app.GUISCARALabel.Text = 'GUI SCARA';
            app.YiLabel = uilabel(app.UIFigure);
            app.YiLabel.Text = 'Yi';
            app.Pos_yi = uieditfield(app.UIFigure, 'numeric');
            app.Pos_yi.Limits = [-140 140];
            app.GraficarposicionButton = uibutton(app.UIFigure, 'push');
            app.GraficarposicionButton.ButtonPushedFcn = createCallbackFcn(app, @GraficarposicionButtonPushed, true);
            app.GraficarposicionButton.Text = 'Graficar posicion';
            app.InicialLabel = uilabel(app.UIFigure);
            app.InicialLabel.Text = 'Inicial';
            app.XiLabel_2 = uilabel(app.UIFigure);
            app.XiLabel_2.Text = 'Xf';
            app.Pos_xf = uieditfield(app.UIFigure, 'numeric');
            app.Pos_xf.Limits = [-180 180];
            app.Pos_xf.Value = 100;
            app.YiLabel_2 = uilabel(app.UIFigure);
            app.YiLabel_2.Text = 'Yf';
            app.Pos_yf = uieditfield(app.UIFigure, 'numeric');
            app.Pos_yf.Limits = [-140 140];
            app.Pos_yf.Value = 80;
            app.FinalLabel = uilabel(app.UIFigure);
            app.FinalLabel.Text = 'Final';
            app.TiempoEditFieldLabel = uilabel(app.UIFigure);
            app.TiempoEditFieldLabel.Text = 'Tiempo';
            app.time = uieditfield(app.UIFigure, 'numeric');
            app.time.Value = 50;
            app.ConectarButton = uibutton(app.UIFigure, 'push');
            app.ConectarButton.ButtonPushedFcn = createCallbackFcn(app, @ConectarButtonPushed, true);
            app.ConectarButton.Text = 'Conectar';
            app.DesconectarButton = uibutton(app.UIFigure, 'push');
            app.DesconectarButton.ButtonPushedFcn = createCallbackFcn(app, @DesconectarButtonPushed, true);
            app.DesconectarButton.Text = 'Desconectar';
            app.EnviarTrayectoriaButton = uibutton(app.UIFigure, 'push');
            app.EnviarTrayectoriaButton.ButtonPushedFcn = createCallbackFcn(app, @EnviarTrayectoriaButtonPushed, true);
            app.EnviarTrayectoriaButton.Text = 'Enviar Trayectoria';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_Robot_Scara

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end